classdef orbitVisualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PlanetColorDropDown           matlab.ui.control.DropDown
        PlanetColorDropDownLabel      matlab.ui.control.Label
        SpacecraftColorDropDown       matlab.ui.control.DropDown
        SpacecraftColorDropDownLabel  matlab.ui.control.Label
        OrbitColorDropDown            matlab.ui.control.DropDown
        OrbitColorDropDownLabel       matlab.ui.control.Label
        SemimajorAxiskmKnob           matlab.ui.control.Knob
        SemimajorAxiskmKnobLabel      matlab.ui.control.Label
        EccentricityKnob              matlab.ui.control.Knob
        EccentricityKnobLabel         matlab.ui.control.Label
        TrueAnomalydegKnob            matlab.ui.control.Knob
        TrueAnomalydegKnobLabel       matlab.ui.control.Label
        ByIanFaberLabel               matlab.ui.control.Label
        ShowPlanetCheckBox            matlab.ui.control.CheckBox
        OrbitVisualizerAppLabel       matlab.ui.control.Label
        SemimajorAxisEditField        matlab.ui.control.NumericEditField
        EccentricityEditField         matlab.ui.control.NumericEditField
        TrueAnomalyEditField          matlab.ui.control.NumericEditField
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        pAxes; % Polar axes
        nu = 0; % True anomaly of spacecraft on orbit (deg)
        e = 0; % Eccentricity of orbit
        a = 3000; % Semimajor axis of orbit (km)
        SCColor = 'r'
        orbitColor = 'k'
        planetColor = 'b'
        showPlanet = true;
    end
    
    methods (Access = private)
        
        function updatePlot(app)
            cla(app.pAxes);
            hold(app.pAxes, 'on');
            theta = 0:pi/1000:2*pi;
            
            r = (app.a*(1-app.e^2))./(1+app.e*cos(theta));
            rSC = (app.a*(1-app.e^2))./(1+app.e*cosd(app.nu));
           
            orbit = polarplot(app.pAxes, theta, r);
            orbit.Color = app.orbitColor;

            SC = polarplot(app.pAxes, pi*app.nu/180, rSC, '.', 'MarkerSize', 14);
            SC.Color = app.SCColor;

            if app.showPlanet
                planet = polarplot(app.pAxes, 0, 0, 'b.', 'MarkerSize', 20);
                planet.Color = app.planetColor;
            end
            

            hold(app.pAxes, 'off');

            if app.e <= 1
                if max(r) < 10000
                    rMax = 10000;
                elseif max(r) == 10000
                    rMax = 15000;
                else
                    rMax = 20000;
                end
            else
                rMax = 25000;
            end

            rlim(app.pAxes, [0, rMax])

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc; close all;
            
            app.nu = 0;
            app.e = 0;
            app.a = 3000;

            app.TrueAnomalyEditField.Value = app.nu;
            app.EccentricityEditField.Value = app.e;
            app.SemimajorAxisEditField.Value = app.a;

            app.TrueAnomalydegKnob.Value = app.nu;
            app.EccentricityKnob.Value = app.e;
            app.SemimajorAxiskmKnob.Value = app.a;
            
            app.pAxes = polaraxes(app.UIFigure);
            app.pAxes.Units = 'pixels';
            app.pAxes.Position = app.UIAxes.Position;
            updatePlot(app)
        end

        % Value changed function: OrbitColorDropDown
        function OrbitColorDropDownValueChanged(app, event)
            app.orbitColor = app.OrbitColorDropDown.Value;
            updatePlot(app);
        end

        % Value changed function: SpacecraftColorDropDown
        function SpacecraftColorDropDownValueChanged(app, event)
            app.SCColor = app.SpacecraftColorDropDown.Value;
            updatePlot(app);
        end

        % Value changing function: TrueAnomalydegKnob
        function TrueAnomalydegKnobValueChanging(app, event)
            app.nu = event.Value;
            app.TrueAnomalyEditField.Value = app.nu;
            updatePlot(app);
        end

        % Value changing function: EccentricityKnob
        function EccentricityKnobValueChanging(app, event)
            app.e = event.Value;
            app.EccentricityEditField.Value = app.e;
            updatePlot(app);
        end

        % Value changing function: SemimajorAxiskmKnob
        function SemimajorAxiskmKnobValueChanging(app, event)
            app.a = event.Value;
            app.SemimajorAxisEditField.Value = app.a;
            updatePlot(app);
        end

        % Value changed function: TrueAnomalyEditField
        function TrueAnomalyEditFieldValueChanged(app, event)
            app.nu = app.TrueAnomalyEditField.Value;
            app.TrueAnomalydegKnob.Value = app.nu;
            updatePlot(app);
        end

        % Value changed function: EccentricityEditField
        function EccentricityEditFieldValueChanged(app, event)
            app.e = app.EccentricityEditField.Value;
            app.EccentricityKnob.Value = app.e;
            updatePlot(app);
        end

        % Value changed function: SemimajorAxisEditField
        function SemimajorAxisEditFieldValueChanged(app, event)
            app.a = app.SemimajorAxisEditField.Value;
            app.SemimajorAxiskmKnob.Value = app.a;
            updatePlot(app);
        end

        % Value changed function: PlanetColorDropDown
        function PlanetColorDropDownValueChanged(app, event)
            app.planetColor = app.PlanetColorDropDown.Value;
            updatePlot(app);
        end

        % Value changed function: ShowPlanetCheckBox
        function ShowPlanetCheckBoxValueChanged(app, event)
            app.showPlanet = app.ShowPlanetCheckBox.Value;
            updatePlot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Orbit Visualizer')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.TrueAnomalyEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrueAnomalyEditField.ValueChangedFcn = createCallbackFcn(app, @TrueAnomalyEditFieldValueChanged, true);
            app.EccentricityEditField = uieditfield(app.UIFigure, 'numeric');
            app.EccentricityEditField.ValueChangedFcn = createCallbackFcn(app, @EccentricityEditFieldValueChanged, true);
            app.SemimajorAxisEditField = uieditfield(app.UIFigure, 'numeric');
            app.SemimajorAxisEditField.ValueChangedFcn = createCallbackFcn(app, @SemimajorAxisEditFieldValueChanged, true);
            app.OrbitVisualizerAppLabel = uilabel(app.UIFigure);
            app.OrbitVisualizerAppLabel.Text = 'Orbit Visualizer App';
            app.ShowPlanetCheckBox = uicheckbox(app.UIFigure);
            app.ShowPlanetCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowPlanetCheckBoxValueChanged, true);
            app.ShowPlanetCheckBox.Text = 'Show Planet';
            app.ShowPlanetCheckBox.Value = true;
            app.ByIanFaberLabel = uilabel(app.UIFigure);
            app.ByIanFaberLabel.Text = 'By: Ian Faber';
            app.TrueAnomalydegKnobLabel = uilabel(app.UIFigure);
            app.TrueAnomalydegKnobLabel.Text = 'True Anomaly (deg)';
            app.TrueAnomalydegKnob = uiknob(app.UIFigure, 'continuous');
            app.TrueAnomalydegKnob.Limits = [-360 360];
            app.TrueAnomalydegKnob.ValueChangingFcn = createCallbackFcn(app, @TrueAnomalydegKnobValueChanging, true);
            app.EccentricityKnobLabel = uilabel(app.UIFigure);
            app.EccentricityKnobLabel.Text = 'Eccentricity';
            app.EccentricityKnob = uiknob(app.UIFigure, 'continuous');
            app.EccentricityKnob.Limits = [0 3];
            app.EccentricityKnob.ValueChangingFcn = createCallbackFcn(app, @EccentricityKnobValueChanging, true);
            app.SemimajorAxiskmKnobLabel = uilabel(app.UIFigure);
            app.SemimajorAxiskmKnobLabel.Text = 'Semimajor Axis (km)';
            app.SemimajorAxiskmKnob = uiknob(app.UIFigure, 'continuous');
            app.SemimajorAxiskmKnob.Limits = [0 10000];
            app.SemimajorAxiskmKnob.ValueChangingFcn = createCallbackFcn(app, @SemimajorAxiskmKnobValueChanging, true);
            app.OrbitColorDropDownLabel = uilabel(app.UIFigure);
            app.OrbitColorDropDownLabel.Text = 'Orbit Color';
            app.OrbitColorDropDown = uidropdown(app.UIFigure);
            app.OrbitColorDropDown.Items = {'Black', 'Red', 'Green', 'Blue', 'Cyan', 'Magenta', 'Yellow'};
            app.OrbitColorDropDown.ItemsData = {'k', 'r', 'g', 'b', 'c', 'm', 'y'};
            app.OrbitColorDropDown.ValueChangedFcn = createCallbackFcn(app, @OrbitColorDropDownValueChanged, true);
            app.OrbitColorDropDown.Value = 'k';
            app.SpacecraftColorDropDownLabel = uilabel(app.UIFigure);
            app.SpacecraftColorDropDownLabel.Text = 'Spacecraft Color';
            app.SpacecraftColorDropDown = uidropdown(app.UIFigure);
            app.SpacecraftColorDropDown.Items = {'Black', 'Red', 'Green', 'Blue', 'Cyan', 'Magenta', 'Yellow'};
            app.SpacecraftColorDropDown.ItemsData = {'k', 'r', 'g', 'b', 'c', 'm', 'y'};
            app.SpacecraftColorDropDown.ValueChangedFcn = createCallbackFcn(app, @SpacecraftColorDropDownValueChanged, true);
            app.SpacecraftColorDropDown.Value = 'r';
            app.PlanetColorDropDownLabel = uilabel(app.UIFigure);
            app.PlanetColorDropDownLabel.Text = 'Planet Color';
            app.PlanetColorDropDown = uidropdown(app.UIFigure);
            app.PlanetColorDropDown.Items = {'Black', 'Red', 'Green', 'Blue', 'Cyan', 'Magenta', 'Yellow'};
            app.PlanetColorDropDown.ItemsData = {'k', 'r', 'g', 'b', 'c', 'm', 'y'};
            app.PlanetColorDropDown.ValueChangedFcn = createCallbackFcn(app, @PlanetColorDropDownValueChanged, true);
            app.PlanetColorDropDown.Value = 'b';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = orbitVisualizer

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end